classdef fitsaathi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        breathingpanel         matlab.ui.container.Panel
        Image5                 matlab.ui.control.Image
        activity_panel         matlab.ui.container.Panel
        graph                  matlab.ui.control.Image
        SEEYOURACTIVITYButton  matlab.ui.control.StateButton
        step_count_panel       matlab.ui.container.Panel
        GetStepsButton         matlab.ui.control.StateButton
        stepsdisp              matlab.ui.control.Label
        label                  matlab.ui.control.Label
        features_panel         matlab.ui.container.Panel
        ExploreButton_3        matlab.ui.control.StateButton
        ExploreButton_2        matlab.ui.control.StateButton
        ExploreButton          matlab.ui.control.StateButton
        Image4                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        home_panel             matlab.ui.container.Panel
        LetsBeginButton        matlab.ui.control.StateButton
        FitSaathiLabel         matlab.ui.control.Label
        Image                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LetsBeginButton
        function LetsBeginButtonValueChanged(app, event)
                value = app.LetsBeginButton.Value;

    % If the button is pressed, hide the home panel and show Panel3
                 if value
                     app.home_panel.Visible = 'off';  % Hide the home panel
                     app.features_panel.Visible = 'on'; % Show Panel3
                 end
        end

        % Callback function
        function ExploreButtonPushed(app, event)
            
        end

        % Value changed function: ExploreButton
        function ExploreButtonValueChanged(app, event)
            value = app.ExploreButton.Value;

    % If the button is pressed, hide the home panel and show Panel3
                 if value
                     app.home_panel.Visible = 'off';  % Hide the home panel
                     app.features_panel.Visible = 'off'; % hide features
                     app.step_count_panel.Visible = 'on'; % Show step count

                 end
        end

        % Callback function
        function step_count_panelButtonDown(app, event)
            
        end

        % Value changed function: GetStepsButton
        function GetStepsButtonValueChanged(app, event)
            value = app.GetStepsButton.Value;
             % Load the data from data1.mat (ensure this file is available)
            dat = load('data1.mat');
            function d = haversine(lat1, lon1, lat2, lon2)
             % Haversine formula to calculate the distance between two points on the Earth
                R = 6371; % Radius of the Earth in kilometers
                dLat = deg2rad(lat2 - lat1); % Difference in latitude in radians
                dLon = deg2rad(lon2 - lon1); % Difference in longitude in radians
    
                 a = sin(dLat/2)^2 + cos(deg2rad(lat1)) * cos(deg2rad(lat2)) * sin(dLon/2)^2;
                    c = 2 * atan2(sqrt(a), sqrt(1 - a));
    
                d = R * c; % Distance in kilometers
            end

            % Extract data from the struct
            lat = dat.Position.latitude;
            lon = dat.Position.longitude;

            % Model code to calculate steps
            earthCirc = 24901; % Earth's circumference in miles
            stride = 2.5; % Average stride length in feet

            % Initialize total distance
            totaldis = 0;

            % Loop through each pair of points
            for i = 1:(length(lat)-1)
                lat1 = lat(i);     % The first latitude
                lat2 = lat(i+1);   % The second latitude
                lon1 = lon(i);     % The first longitude
                lon2 = lon(i+1);   % The second longitude
                degDis = haversine(lat1, lon1, lat2, lon2); % Using haversine formula
                totaldis = totaldis + degDis;
            end

            % Convert total distance to feet
            totaldis_ft = totaldis * 5280;

            % Calculate the total number of steps
            steps = totaldis_ft / stride;

            % Update the label with the number of steps
            if value
               app.stepsdisp.Visible = 'on'; % Show steps
               app.stepsdisp.Text = ['You Took ', num2str(steps), ' Steps'];
            end
        end

        % Value changed function: SEEYOURACTIVITYButton
        function SEEYOURACTIVITYButtonValueChanged(app, event)
            value = app.SEEYOURACTIVITYButton.Value;

    % If the button is pressed, hide the home panel and show Panel3
                 if value
                     app.graph.Visible = 'on'; 
                 end
        end

        % Value changed function: ExploreButton_2
        function ExploreButton_2ValueChanged(app, event)
            value = app.ExploreButton_2.Value;
             if value
                       app.features_panel.Visible = 'off';  

                     app.activity_panel.Visible = 'on';  
             end
        end

        % Value changed function: ExploreButton_3
        function ExploreButton_3ValueChanged(app, event)
            value = app.ExploreButton_3.Value;
            if value
                       app.features_panel.Visible = 'off';  

                     app.breathingpanel.Visible = 'on';  
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.home_panel = uipanel(app.Panel);
            app.home_panel.Title = 'HOME';
            app.Image = uiimage(app.home_panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-10-01 at 12.03.27_cf75508d.jpg');
            app.FitSaathiLabel = uilabel(app.home_panel);
            app.FitSaathiLabel.Text = 'FitSaathi';
            app.LetsBeginButton = uibutton(app.home_panel, 'state');
            app.LetsBeginButton.ValueChangedFcn = createCallbackFcn(app, @LetsBeginButtonValueChanged, true);
            app.LetsBeginButton.Text = 'Let''s Begin';
            app.features_panel = uipanel(app.Panel);
            app.features_panel.Title = 'FEATURES';
            app.features_panel.Visible = 'off';
            app.Image2 = uiimage(app.features_panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'd653c5c3-4c64-41f7-8551-60eb468aa103[1].jpeg');
            app.Image3 = uiimage(app.features_panel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Premium_Vector___Volleyball_player_serving_the_ball__black_and_white_vector_outline[1].jpeg');
            app.Image4 = uiimage(app.features_panel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Yoga_pose_minimalist_wall_art_print[1].jpeg');
            app.ExploreButton = uibutton(app.features_panel, 'state');
            app.ExploreButton.ValueChangedFcn = createCallbackFcn(app, @ExploreButtonValueChanged, true);
            app.ExploreButton.Text = 'Explore';
            app.ExploreButton_2 = uibutton(app.features_panel, 'state');
            app.ExploreButton_2.ValueChangedFcn = createCallbackFcn(app, @ExploreButton_2ValueChanged, true);
            app.ExploreButton_2.Text = 'Explore';
            app.ExploreButton_3 = uibutton(app.features_panel, 'state');
            app.ExploreButton_3.ValueChangedFcn = createCallbackFcn(app, @ExploreButton_3ValueChanged, true);
            app.ExploreButton_3.Text = 'Explore';
            app.step_count_panel = uipanel(app.Panel);
            app.step_count_panel.Title = 'STEP COUNT';
            app.step_count_panel.Visible = 'off';
            app.label = uilabel(app.step_count_panel);
            app.label.Text = {'Walking has many advantages'; ''; '1. Improves Cardiovascular Health'; '2. Boosts Mental Health'; '3. Aids in Weight Management '; '4. Enhances Muscle Tone and Endurance  '; '5. Promotes Longevity'};
            app.stepsdisp = uilabel(app.step_count_panel);
            app.stepsdisp.Visible = 'off';
            app.stepsdisp.Text = '';
            app.GetStepsButton = uibutton(app.step_count_panel, 'state');
            app.GetStepsButton.ValueChangedFcn = createCallbackFcn(app, @GetStepsButtonValueChanged, true);
            app.GetStepsButton.Text = 'Get Steps';
            app.activity_panel = uipanel(app.Panel);
            app.activity_panel.Title = 'ACTIVITY';
            app.activity_panel.Visible = 'off';
            app.SEEYOURACTIVITYButton = uibutton(app.activity_panel, 'state');
            app.SEEYOURACTIVITYButton.ValueChangedFcn = createCallbackFcn(app, @SEEYOURACTIVITYButtonValueChanged, true);
            app.SEEYOURACTIVITYButton.Text = 'SEE YOUR ACTIVITY';
            app.graph = uiimage(app.activity_panel);
            app.graph.Visible = 'off';
            app.graph.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-10-01 140615.png');
            app.breathingpanel = uipanel(app.Panel);
            app.breathingpanel.Title = 'BREATHING EXERCISES';
            app.breathingpanel.Visible = 'off';
            app.Image5 = uiimage(app.breathingpanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2024-10-01 at 14.16.06_76508865.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitsaathi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end